classdef AirsoftCalculationTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ARTEMISUIFigure              matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        Tab_Menu                     matlab.ui.container.Tab
        GridLayout2                  matlab.ui.container.GridLayout
        UserManualGermanButton       matlab.ui.control.Button
        Image                        matlab.ui.control.Image
        Text_Author                  matlab.ui.control.Label
        Text_Title_2                 matlab.ui.control.Label
        Text_Title                   matlab.ui.control.Label
        Tab_Params                   matlab.ui.container.Tab
        GridLayout                   matlab.ui.container.GridLayout
        SimulationParametersPanel    matlab.ui.container.Panel
        Lamp_Wind                    matlab.ui.control.Lamp
        Toggle_wind                  matlab.ui.control.ToggleSwitch
        WindLabel                    matlab.ui.control.Label
        Spinner_air_density          matlab.ui.control.Spinner
        AngledegLabel_4              matlab.ui.control.Label
        Spinner_gravity              matlab.ui.control.Spinner
        AngledegLabel_3              matlab.ui.control.Label
        Toggle_spin_decay            matlab.ui.control.ToggleSwitch
        SpinDecaySwitchLabel         matlab.ui.control.Label
        Toggle_magnus                matlab.ui.control.ToggleSwitch
        MagnusEffectSwitchLabel      matlab.ui.control.Label
        Toggle_friction              matlab.ui.control.ToggleSwitch
        AirFrictionSwitchLabel       matlab.ui.control.Label
        Toggle_gravity               matlab.ui.control.ToggleSwitch
        GravitySwitchLabel           matlab.ui.control.Label
        Lamp_SpinDecay               matlab.ui.control.Lamp
        Lamp_MagnusEffect            matlab.ui.control.Lamp
        Lamp_AirFriction             matlab.ui.control.Lamp
        Lamp_Gravity                 matlab.ui.control.Lamp
        OutputGaugesPanel            matlab.ui.container.Panel
        Gauge_spin                   matlab.ui.control.LinearGauge
        PercentageofInitialSpinoftheMaximumPossibleSpinLabel  matlab.ui.control.Label
        KineticEnergyJLabel          matlab.ui.control.Label
        Gauge_energy                 matlab.ui.control.LinearGauge
        Gauge_velocity               matlab.ui.control.LinearGauge
        KineticEnergyJLabel_2        matlab.ui.control.Label
        AngleIndicatorLabel          matlab.ui.control.Label
        Gauge_angle                  matlab.ui.control.NinetyDegreeGauge
        CalculationPanel             matlab.ui.container.Panel
        Toggle_2D3D                  matlab.ui.control.RockerSwitch
        ModeLabel                    matlab.ui.control.Label
        Spinner_tspan                matlab.ui.control.Spinner
        MaximumSimulationTimesSpinnerLabel  matlab.ui.control.Label
        Lamp_Feedback                matlab.ui.control.Lamp
        Button_StartCalculation      matlab.ui.control.Button
        AirSoftParametersPanel       matlab.ui.container.Panel
        Spinner_spin                 matlab.ui.control.Spinner
        AngledegLabel_6              matlab.ui.control.Label
        Spinner_height               matlab.ui.control.Spinner
        AngledegLabel_2              matlab.ui.control.Label
        Spinner_angle                matlab.ui.control.Spinner
        AngledegLabel                matlab.ui.control.Label
        BBParametersPanel            matlab.ui.container.Panel
        Toggle_Ekin_v                matlab.ui.control.RockerSwitch
        InputSwitchLabel             matlab.ui.control.Label
        Spinner_velocity             matlab.ui.control.Spinner
        ProjectilevelocitymsLabel    matlab.ui.control.Label
        Spinner_diameter             matlab.ui.control.Spinner
        ProjectileDiametermmLabel    matlab.ui.control.Label
        Spinner_mass                 matlab.ui.control.Spinner
        ProjectileMassgSpinnerLabel  matlab.ui.control.Label
        Spinner_energy               matlab.ui.control.Spinner
        KineticEnergyJSpinnerLabel   matlab.ui.control.Label
        WindSpeedDownrangeXTab       matlab.ui.container.Tab
        GridLayout4                  matlab.ui.container.GridLayout
        SineFunction03Panel          matlab.ui.container.Panel
        LampSine03                   matlab.ui.control.Lamp
        Spinner_Period03             matlab.ui.control.Spinner
        PeriodsSpinnerLabel_3        matlab.ui.control.Label
        Spinner_Offset03             matlab.ui.control.Spinner
        OffsetmsSpinnerLabel_3       matlab.ui.control.Label
        Spinner_Amplitude03          matlab.ui.control.Spinner
        AmplitudemsSpinnerLabel_3    matlab.ui.control.Label
        Spinner_PhaseAngle03         matlab.ui.control.Spinner
        PhaseAngleLabel_3            matlab.ui.control.Label
        SwitchSine03                 matlab.ui.control.RockerSwitch
        SineFunction02Panel          matlab.ui.container.Panel
        LampSine02                   matlab.ui.control.Lamp
        Spinner_Period02             matlab.ui.control.Spinner
        PeriodsSpinnerLabel_2        matlab.ui.control.Label
        Spinner_Offset02             matlab.ui.control.Spinner
        OffsetmsSpinnerLabel_2       matlab.ui.control.Label
        Spinner_Amplitude02          matlab.ui.control.Spinner
        AmplitudemsSpinnerLabel_2    matlab.ui.control.Label
        Spinner_PhaseAngle02         matlab.ui.control.Spinner
        PhaseAngleLabel_2            matlab.ui.control.Label
        SwitchSine02                 matlab.ui.control.RockerSwitch
        SineFunction01Panel          matlab.ui.container.Panel
        LampSine01                   matlab.ui.control.Lamp
        Spinner_Period01             matlab.ui.control.Spinner
        PeriodsSpinnerLabel          matlab.ui.control.Label
        Spinner_Offset01             matlab.ui.control.Spinner
        OffsetmsSpinnerLabel         matlab.ui.control.Label
        Spinner_Amplitude01          matlab.ui.control.Spinner
        AmplitudemsSpinnerLabel      matlab.ui.control.Label
        Spinner_PhaseAngle01         matlab.ui.control.Spinner
        PhaseAngleLabel              matlab.ui.control.Label
        SwitchSine01                 matlab.ui.control.RockerSwitch
        WindDiagramPanel             matlab.ui.container.Panel
        GridLayout5                  matlab.ui.container.GridLayout
        WindPlot                     matlab.ui.control.UIAxes
        WindSpeedCrossrangeYTab      matlab.ui.container.Tab
        GridLayout4_2                matlab.ui.container.GridLayout
        SineFunction03Panel_2        matlab.ui.container.Panel
        LampSine03_2                 matlab.ui.control.Lamp
        Spinner_Period03_2           matlab.ui.control.Spinner
        PeriodsSpinnerLabel_6        matlab.ui.control.Label
        Spinner_Offset03_2           matlab.ui.control.Spinner
        OffsetmsSpinnerLabel_6       matlab.ui.control.Label
        Spinner_Amplitude03_2        matlab.ui.control.Spinner
        AmplitudemsSpinnerLabel_6    matlab.ui.control.Label
        Spinner_PhaseAngle03_2       matlab.ui.control.Spinner
        PhaseAngleLabel_6            matlab.ui.control.Label
        SwitchSine03_2               matlab.ui.control.RockerSwitch
        SineFunction02Panel_2        matlab.ui.container.Panel
        LampSine02_2                 matlab.ui.control.Lamp
        Spinner_Period02_2           matlab.ui.control.Spinner
        PeriodsSpinnerLabel_5        matlab.ui.control.Label
        Spinner_Offset02_2           matlab.ui.control.Spinner
        OffsetmsSpinnerLabel_5       matlab.ui.control.Label
        Spinner_Amplitude02_2        matlab.ui.control.Spinner
        AmplitudemsSpinnerLabel_5    matlab.ui.control.Label
        Spinner_PhaseAngle02_2       matlab.ui.control.Spinner
        PhaseAngleLabel_5            matlab.ui.control.Label
        SwitchSine02_2               matlab.ui.control.RockerSwitch
        SineFunction01Panel_2        matlab.ui.container.Panel
        LampSine01_2                 matlab.ui.control.Lamp
        Spinner_Period01_2           matlab.ui.control.Spinner
        PeriodsSpinnerLabel_4        matlab.ui.control.Label
        Spinner_Offset01_2           matlab.ui.control.Spinner
        OffsetmsSpinnerLabel_4       matlab.ui.control.Label
        Spinner_Amplitude01_2        matlab.ui.control.Spinner
        AmplitudemsSpinnerLabel_4    matlab.ui.control.Label
        Spinner_PhaseAngle01_2       matlab.ui.control.Spinner
        PhaseAngleLabel_4            matlab.ui.control.Label
        SwitchSine01_2               matlab.ui.control.RockerSwitch
        WindDiagramPanel_2           matlab.ui.container.Panel
        GridLayout5_2                matlab.ui.container.GridLayout
        WindPlot_2                   matlab.ui.control.UIAxes
        WindSpeedCrossrangeZTab      matlab.ui.container.Tab
        GridLayout4_3                matlab.ui.container.GridLayout
        SineFunction03Panel_3        matlab.ui.container.Panel
        LampSine03_3                 matlab.ui.control.Lamp
        Spinner_Period03_3           matlab.ui.control.Spinner
        PeriodsSpinnerLabel_9        matlab.ui.control.Label
        Spinner_Offset03_3           matlab.ui.control.Spinner
        OffsetmsSpinnerLabel_9       matlab.ui.control.Label
        Spinner_Amplitude03_3        matlab.ui.control.Spinner
        AmplitudemsSpinnerLabel_9    matlab.ui.control.Label
        Spinner_PhaseAngle03_3       matlab.ui.control.Spinner
        PhaseAngleLabel_9            matlab.ui.control.Label
        SwitchSine03_3               matlab.ui.control.RockerSwitch
        SineFunction02Panel_3        matlab.ui.container.Panel
        LampSine02_3                 matlab.ui.control.Lamp
        Spinner_Period02_3           matlab.ui.control.Spinner
        PeriodsSpinnerLabel_8        matlab.ui.control.Label
        Spinner_Offset02_3           matlab.ui.control.Spinner
        OffsetmsSpinnerLabel_8       matlab.ui.control.Label
        Spinner_Amplitude02_3        matlab.ui.control.Spinner
        AmplitudemsSpinnerLabel_8    matlab.ui.control.Label
        Spinner_PhaseAngle02_3       matlab.ui.control.Spinner
        PhaseAngleLabel_8            matlab.ui.control.Label
        SwitchSine02_3               matlab.ui.control.RockerSwitch
        SineFunction01Panel_3        matlab.ui.container.Panel
        LampSine01_3                 matlab.ui.control.Lamp
        Spinner_Period01_3           matlab.ui.control.Spinner
        PeriodsSpinnerLabel_7        matlab.ui.control.Label
        Spinner_Offset01_3           matlab.ui.control.Spinner
        OffsetmsSpinnerLabel_7       matlab.ui.control.Label
        Spinner_Amplitude01_3        matlab.ui.control.Spinner
        AmplitudemsSpinnerLabel_7    matlab.ui.control.Label
        Spinner_PhaseAngle01_3       matlab.ui.control.Spinner
        PhaseAngleLabel_7            matlab.ui.control.Label
        SwitchSine01_3               matlab.ui.control.RockerSwitch
        WindDiagramPanel_3           matlab.ui.container.Panel
        GridLayout5_3                matlab.ui.container.GridLayout
        WindPlot_3                   matlab.ui.control.UIAxes
        Tab_SimPlots                 matlab.ui.container.Tab
        GridLayout6                  matlab.ui.container.GridLayout
        Plot2D_tz                    matlab.ui.control.UIAxes
        Plot2D_tv                    matlab.ui.control.UIAxes
        Plot2D_tE1                   matlab.ui.control.UIAxes
        Plot2D_xz                    matlab.ui.control.UIAxes
        DTrajectoryTab               matlab.ui.container.Tab
        GridLayout7                  matlab.ui.container.GridLayout
        Plot3D_Trajectory            matlab.ui.control.UIAxes
        EnergyPlotsTab               matlab.ui.container.Tab
        GridLayout8                  matlab.ui.container.GridLayout
        Plot2D_tEpot                 matlab.ui.control.UIAxes
        Plot2D_tErot                 matlab.ui.control.UIAxes
        Plot2D_tEkin                 matlab.ui.control.UIAxes
        Plot2D_tE2                   matlab.ui.control.UIAxes
        SpeedPlotsTab                matlab.ui.container.Tab
        GridLayout10                 matlab.ui.container.GridLayout
        Plot2D_tv2                   matlab.ui.control.UIAxes
        Plot2D_tvz                   matlab.ui.control.UIAxes
        Plot2D_tvy                   matlab.ui.control.UIAxes
        Plot2D_tvx                   matlab.ui.control.UIAxes
        PositionPlotsTab             matlab.ui.container.Tab
        GridLayout9                  matlab.ui.container.GridLayout
        Plot2D_tx                    matlab.ui.control.UIAxes
        Plot2D_ty                    matlab.ui.control.UIAxes
        Plot2D_tz2                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        %% Simulation Settings
        Bool_Gravity;           % Is Gravity Enabled in the Simulation?
        Bool_AirFriction;       % Is Air friction Enabled in the Simulation?
        Bool_MagnusEffect;      % Is the Magnus Effect Enabled in the Simulation?
        Bool_SpinDecay;         % Is the Spin of the BB getting slower during the Sim?
        Bool_Wind;              % Is Wind enabled during the Sim
        Bool_2D3D               % Either 2D or 3D
        
        %% Projectile Properties
        BB_Mass;                % Mass of the projectile in kg
        BB_Diameter;            % Diameter of the Projectile in m
        BB_Angle_Initial;       % Angle of whicht the Projectile is shot [0-90 deg]
        BB_Height_Initial;      % Height of the Projecile at start in m
        BB_Hop_Up;              % How much Hop-Up [0-100%] (0% => No rotation / 100% => Rolling without slipping)
        BB_Velocity_Initial;    % Inital Muzzle Velocity in m/s
        BB_Spin_Initial;        % Initial spin in RPM
        BB_Ekin_Initial;        % Initial Kinetic Energy in J
        BB_Erot_Initial;        % Initial Rotational Energy in J
        
        %% Simulation Properties
        SIM_Gravity;            % Gravity in m/s^2
        SIM_Air_Density;        % Air Density in kg/m^3
        tspan_end;              % Max Simulation Time
        SIM_Wind_X_Properties   % Descriptor of values for contruction of a sine Function
        SIM_Wind_Y_Properties   % Descriptor of values for contruction of a sine Function
        SIM_Wind_Z_Properties   % Descriptor of values for contruction of a sine Function
        
        %% Simulated Values
        ODE_t                   % time in s
        ODE_x                   % distance from origin downrange in m
        ODE_y                   % distance from origin cross in m
        ODE_z                   % height in m
        ODE_v                   % Projectile velocity in m/s
        ODE_vx                  % Projectile velocity X in m/s
        ODE_vy                  % Projectile velocity Y in m/s
        ODE_vz                  % Projectile velocity Z in m/s
        ODE_Ekin                % Kinetic Energy in J
        ODE_Epot                % Potential Energy in J
        ODE_Erot                % Rotational Energy in J
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Run every Input related Subroutine at startup
            IO_InputValues(app);
            IO_ToggleFunctions(app);
            IO_InputWindPanel(app);
            IO_InputWindPanel_Y(app);
            IO_InputWindPanel_Z(app);
        end

        % Value changed function: Spinner_diameter, Spinner_energy, 
        % ...and 3 other components
        function BBParametersChanged(app, event)
            % Callback if a value at the BB Parameters Panel was changed
            IO_InputValues(app);
        end

        % Value changed function: Spinner_angle, Spinner_height, 
        % ...and 1 other component
        function airsoftParametersChanged(app, event)
            % Callback if a value at the Airsoft Parameters Panel was changed
            IO_InputValues(app);
        end

        % Value changed function: Spinner_air_density, Spinner_gravity
        function simulationParametersChanged(app, event)
            % Callback if a value at the Simulation Parameters Panel was changed
            IO_InputValues(app);
        end

        % Value changed function: Toggle_friction, Toggle_gravity, 
        % ...and 3 other components
        function simulationParametersEnable(app, event)
            % Callback if a Switch at the Simulation Parameters Panel was changed
            IO_ToggleFunctions(app);
        end

        % Value changed function: Spinner_tspan
        function tspanValueChanged(app, event)
            % Callback if the tspan Spinner was changed
            IO_InputValues(app);
        end

        % Button pushed function: Button_StartCalculation
        function calculationButtonPressed(app, event)
            % Callback if the Calculation Button was pressed
            IO_CalculationButtonPushed(app);
        end

        % Value changed function: Spinner_Amplitude01, Spinner_Offset01, 
        % ...and 3 other components
        function windPanelSine01(app, event)
            % Sine 1 Panel in the Wind Tab
            IO_InputWindPanel(app);
        end

        % Value changed function: Spinner_Amplitude02, Spinner_Offset02, 
        % ...and 3 other components
        function windPanelSine02(app, event)
            % Sine 2 Panel in the Wind Tab
            IO_InputWindPanel(app);
        end

        % Value changed function: Spinner_Amplitude03, Spinner_Offset03, 
        % ...and 3 other components
        function windPanelSine03(app, event)
            % Sine 3 Panel in the Wind Tab
            IO_InputWindPanel(app);
        end

        % Value changed function: Spinner_Amplitude01_2, 
        % ...and 14 other components
        function Spinner_PhaseAngle01_2ValueChanged(app, event)
            IO_InputWindPanel_Y(app);
        end

        % Value changed function: Toggle_2D3D
        function Toggle_2D3DValueChanged(app, event)
            
            IO_ToggleFunctions(app);
        end

        % Value changed function: Spinner_Amplitude01_3, 
        % ...and 14 other components
        function Spinner_PhaseAngle01_3ValueChanged(app, event)
            IO_InputWindPanel_Z(app);
        end

        % Button pushed function: UserManualGermanButton
        function UserManualGermanButtonPushed(app, event)
            IO_OpenPDF();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ARTEMISUIFigure = uifigure('Visible', 'off');
            app.ARTEMISUIFigure.Name = 'ARTEMIS';
            app.ARTEMISUIFigure.Icon = fullfile(pathToMLAPP, 'img', 'Airsoft_img.png');
            app.TabGroup = uitabgroup(app.ARTEMISUIFigure);
            app.Tab_Menu = uitab(app.TabGroup);
            app.Tab_Menu.Title = 'Menu';
            app.GridLayout2 = uigridlayout(app.Tab_Menu);
            app.Text_Title = uilabel(app.GridLayout2);
            app.Text_Title.Text = 'ARTEMIS';
            app.Text_Title_2 = uilabel(app.GridLayout2);
            app.Text_Title_2.Text = {'Airsoft Range and Trajectory Evaluation '; 'using MATLAB for Interactive Simulations'};
            app.Text_Author = uilabel(app.GridLayout2);
            app.Text_Author.Text = {'    P. Kruppe, '; '    M. Reichhoff, '; '    M. Goldschmidt '};
            app.Image = uiimage(app.GridLayout2);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'img', 'Airsoft_img_3.png');
            app.UserManualGermanButton = uibutton(app.GridLayout2, 'push');
            app.UserManualGermanButton.ButtonPushedFcn = createCallbackFcn(app, @UserManualGermanButtonPushed, true);
            app.UserManualGermanButton.Icon = fullfile(pathToMLAPP, 'img', 'windows-help-icon-25.png');
            app.UserManualGermanButton.Text = {'User Manual'; '(German)'};
            app.Tab_Params = uitab(app.TabGroup);
            app.Tab_Params.Title = 'Parameters';
            app.GridLayout = uigridlayout(app.Tab_Params);
            app.BBParametersPanel = uipanel(app.GridLayout);
            app.BBParametersPanel.Title = 'BB Parameters';
            app.KineticEnergyJSpinnerLabel = uilabel(app.BBParametersPanel);
            app.KineticEnergyJSpinnerLabel.Text = 'Kinetic Energy [J]';
            app.Spinner_energy = uispinner(app.BBParametersPanel);
            app.Spinner_energy.Step = 0.1;
            app.Spinner_energy.Limits = [0.1 1500];
            app.Spinner_energy.ValueChangedFcn = createCallbackFcn(app, @BBParametersChanged, true);
            app.Spinner_energy.Tooltip = {'The Kinetic Energy of the projectile when it leaves the barrel'};
            app.Spinner_energy.Value = 0.2;
            app.ProjectileMassgSpinnerLabel = uilabel(app.BBParametersPanel);
            app.ProjectileMassgSpinnerLabel.Text = 'Projectile Mass [g]';
            app.Spinner_mass = uispinner(app.BBParametersPanel);
            app.Spinner_mass.Step = 0.1;
            app.Spinner_mass.Limits = [0.01 3];
            app.Spinner_mass.ValueChangedFcn = createCallbackFcn(app, @BBParametersChanged, true);
            app.Spinner_mass.Tooltip = {'The mass of the BB Projectile.'; 'Usually between 0.12 - 0.4 grams'};
            app.Spinner_mass.Value = 0.3;
            app.ProjectileDiametermmLabel = uilabel(app.BBParametersPanel);
            app.ProjectileDiametermmLabel.Text = 'Projectile Diameter [mm]';
            app.Spinner_diameter = uispinner(app.BBParametersPanel);
            app.Spinner_diameter.Step = 0.1;
            app.Spinner_diameter.Limits = [1 10];
            app.Spinner_diameter.ValueChangedFcn = createCallbackFcn(app, @BBParametersChanged, true);
            app.Spinner_diameter.Tooltip = {'The Diameter of the BB'; 'Usually 6mm'};
            app.Spinner_diameter.Value = 6;
            app.ProjectilevelocitymsLabel = uilabel(app.BBParametersPanel);
            app.ProjectilevelocitymsLabel.Text = 'Projectile velocity [m/s]';
            app.Spinner_velocity = uispinner(app.BBParametersPanel);
            app.Spinner_velocity.Step = 0.1;
            app.Spinner_velocity.Limits = [0 1000];
            app.Spinner_velocity.ValueChangedFcn = createCallbackFcn(app, @BBParametersChanged, true);
            app.Spinner_velocity.Tooltip = {'The Muzzle velocity of the projectile when it leaves the barrel'};
            app.Spinner_velocity.Value = 100;
            app.InputSwitchLabel = uilabel(app.BBParametersPanel);
            app.InputSwitchLabel.Text = 'Input';
            app.Toggle_Ekin_v = uiswitch(app.BBParametersPanel, 'rocker');
            app.Toggle_Ekin_v.Items = {'Velocity', 'Kinetic Energy'};
            app.Toggle_Ekin_v.ItemsData = {'v', 'Ekin'};
            app.Toggle_Ekin_v.ValueChangedFcn = createCallbackFcn(app, @BBParametersChanged, true);
            app.Toggle_Ekin_v.Tooltip = {'Switch between Kinetic and Velocity Inputs'};
            app.Toggle_Ekin_v.Value = 'v';
            app.AirSoftParametersPanel = uipanel(app.GridLayout);
            app.AirSoftParametersPanel.Title = 'AirSoft Parameters';
            app.AngledegLabel = uilabel(app.AirSoftParametersPanel);
            app.AngledegLabel.Text = 'Angle [deg]';
            app.Spinner_angle = uispinner(app.AirSoftParametersPanel);
            app.Spinner_angle.Limits = [-80 80];
            app.Spinner_angle.ValueChangedFcn = createCallbackFcn(app, @airsoftParametersChanged, true);
            app.Spinner_angle.Tooltip = {'Angle at which the projectile is shot. 0° is parallel to the ground. 45° is diagonal up.'; '-45° is diagonal down.'};
            app.Spinner_angle.Value = 10;
            app.AngledegLabel_2 = uilabel(app.AirSoftParametersPanel);
            app.AngledegLabel_2.Text = 'Height [m]';
            app.Spinner_height = uispinner(app.AirSoftParametersPanel);
            app.Spinner_height.Step = 0.1;
            app.Spinner_height.Limits = [0 100];
            app.Spinner_height.ValueChangedFcn = createCallbackFcn(app, @airsoftParametersChanged, true);
            app.Spinner_height.Tooltip = {'The Height from which the gun is shot. The ground in this simulaiton is perfectly flat.'};
            app.Spinner_height.Value = 1.5;
            app.AngledegLabel_6 = uilabel(app.AirSoftParametersPanel);
            app.AngledegLabel_6.Text = 'Spin [RPM]';
            app.Spinner_spin = uispinner(app.AirSoftParametersPanel);
            app.Spinner_spin.Step = 100;
            app.Spinner_spin.Limits = [0 50000];
            app.Spinner_spin.ValueChangedFcn = createCallbackFcn(app, @airsoftParametersChanged, true);
            app.Spinner_spin.Tooltip = {'The Spin that the BB has when exiting the Barrel.'};
            app.Spinner_spin.Value = 1000;
            app.CalculationPanel = uipanel(app.GridLayout);
            app.CalculationPanel.Title = 'Calculation';
            app.Button_StartCalculation = uibutton(app.CalculationPanel, 'push');
            app.Button_StartCalculation.ButtonPushedFcn = createCallbackFcn(app, @calculationButtonPressed, true);
            app.Button_StartCalculation.Tooltip = {'Start the Calculation.'; 'This may take a few seconds'};
            app.Button_StartCalculation.Text = 'Calculate';
            app.Lamp_Feedback = uilamp(app.CalculationPanel);
            app.MaximumSimulationTimesSpinnerLabel = uilabel(app.CalculationPanel);
            app.MaximumSimulationTimesSpinnerLabel.Text = 'Maximum Simulation Time [s]';
            app.Spinner_tspan = uispinner(app.CalculationPanel);
            app.Spinner_tspan.Limits = [0 3600];
            app.Spinner_tspan.ValueChangedFcn = createCallbackFcn(app, @tspanValueChanged, true);
            app.Spinner_tspan.Tooltip = {'Simulation Time'; 'Adjust for the Simulation'};
            app.Spinner_tspan.Value = 50;
            app.ModeLabel = uilabel(app.CalculationPanel);
            app.ModeLabel.Text = 'Mode';
            app.Toggle_2D3D = uiswitch(app.CalculationPanel, 'rocker');
            app.Toggle_2D3D.Items = {'2D', '3D'};
            app.Toggle_2D3D.ItemsData = [0 1];
            app.Toggle_2D3D.ValueChangedFcn = createCallbackFcn(app, @Toggle_2D3DValueChanged, true);
            app.Toggle_2D3D.Tooltip = {'Switch between 2D and 3D Mode.'; 'In 3D Mode the Y axis will be modeled'};
            app.Toggle_2D3D.Value = 0;
            app.OutputGaugesPanel = uipanel(app.GridLayout);
            app.OutputGaugesPanel.Title = 'Output Gauges';
            app.Gauge_angle = uigauge(app.OutputGaugesPanel, 'ninetydegree');
            app.Gauge_angle.Limits = [0 90];
            app.Gauge_angle.ScaleDirection = 'counterclockwise';
            app.Gauge_angle.Value = 10;
            app.AngleIndicatorLabel = uilabel(app.OutputGaugesPanel);
            app.AngleIndicatorLabel.Text = 'Angle Indicator [°]';
            app.KineticEnergyJLabel_2 = uilabel(app.OutputGaugesPanel);
            app.KineticEnergyJLabel_2.Text = 'Projectile Speed [m/s]';
            app.Gauge_velocity = uigauge(app.OutputGaugesPanel, 'linear');
            app.Gauge_velocity.Limits = [0 1000];
            app.Gauge_velocity.MajorTicks = [0 100 200 300 400 500 600 700 800 900 1000];
            app.Gauge_velocity.Value = 2;
            app.Gauge_energy = uigauge(app.OutputGaugesPanel, 'linear');
            app.Gauge_energy.Limits = [0 10];
            app.Gauge_energy.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.Gauge_energy.Value = 2;
            app.KineticEnergyJLabel = uilabel(app.OutputGaugesPanel);
            app.KineticEnergyJLabel.Text = 'Kinetic Energy [J]';
            app.PercentageofInitialSpinoftheMaximumPossibleSpinLabel = uilabel(app.OutputGaugesPanel);
            app.PercentageofInitialSpinoftheMaximumPossibleSpinLabel.Text = 'Percentage of Initial Spin of the Maximum possible Spin [%]';
            app.Gauge_spin = uigauge(app.OutputGaugesPanel, 'linear');
            app.Gauge_spin.Limits = [0 2];
            app.Gauge_spin.MajorTicks = [0 0.25 0.5 0.75 1 1.25 1.5 1.75 2];
            app.Gauge_spin.MajorTickLabels = {'0', '0.25', '0.5', '0.75', '1', '1.25', '1.5', '1.75', '2'};
            app.SimulationParametersPanel = uipanel(app.GridLayout);
            app.SimulationParametersPanel.Title = 'Simulation Parameters';
            app.Lamp_Gravity = uilamp(app.SimulationParametersPanel);
            app.Lamp_AirFriction = uilamp(app.SimulationParametersPanel);
            app.Lamp_MagnusEffect = uilamp(app.SimulationParametersPanel);
            app.Lamp_SpinDecay = uilamp(app.SimulationParametersPanel);
            app.GravitySwitchLabel = uilabel(app.SimulationParametersPanel);
            app.GravitySwitchLabel.Text = 'Gravity';
            app.Toggle_gravity = uiswitch(app.SimulationParametersPanel, 'toggle');
            app.Toggle_gravity.ItemsData = [0 1];
            app.Toggle_gravity.ValueChangedFcn = createCallbackFcn(app, @simulationParametersEnable, true);
            app.Toggle_gravity.Tooltip = {'Toggle Gravity'; '0g is not Realistic'};
            app.Toggle_gravity.Value = 1;
            app.AirFrictionSwitchLabel = uilabel(app.SimulationParametersPanel);
            app.AirFrictionSwitchLabel.Text = 'Air Friction';
            app.Toggle_friction = uiswitch(app.SimulationParametersPanel, 'toggle');
            app.Toggle_friction.ItemsData = [0 1];
            app.Toggle_friction.ValueChangedFcn = createCallbackFcn(app, @simulationParametersEnable, true);
            app.Toggle_friction.Tooltip = {'Toggle Air Friction'; 'Keep ON, when using with Magnus effect for better accuracy'};
            app.Toggle_friction.Value = 1;
            app.MagnusEffectSwitchLabel = uilabel(app.SimulationParametersPanel);
            app.MagnusEffectSwitchLabel.Text = 'Magnus Effect';
            app.Toggle_magnus = uiswitch(app.SimulationParametersPanel, 'toggle');
            app.Toggle_magnus.ItemsData = [0 1];
            app.Toggle_magnus.ValueChangedFcn = createCallbackFcn(app, @simulationParametersEnable, true);
            app.Toggle_magnus.Tooltip = {'Toggle wether the Magnus effect is calculated.'; 'Acts as 0 Spin when active'};
            app.Toggle_magnus.Value = 1;
            app.SpinDecaySwitchLabel = uilabel(app.SimulationParametersPanel);
            app.SpinDecaySwitchLabel.Text = 'Spin Decay';
            app.Toggle_spin_decay = uiswitch(app.SimulationParametersPanel, 'toggle');
            app.Toggle_spin_decay.ItemsData = [0 1];
            app.Toggle_spin_decay.ValueChangedFcn = createCallbackFcn(app, @simulationParametersEnable, true);
            app.Toggle_spin_decay.Tooltip = {'Toggles if the Spin is decaying during the Simulation due to drag'};
            app.Toggle_spin_decay.Value = 1;
            app.AngledegLabel_3 = uilabel(app.SimulationParametersPanel);
            app.AngledegLabel_3.Text = 'Gravity [m/s²]';
            app.Spinner_gravity = uispinner(app.SimulationParametersPanel);
            app.Spinner_gravity.Step = 0.5;
            app.Spinner_gravity.Limits = [0 100];
            app.Spinner_gravity.ValueChangedFcn = createCallbackFcn(app, @simulationParametersChanged, true);
            app.Spinner_gravity.Tooltip = {'Suface gravity of the Simulation.'; 'Moon: 1.7'; 'Mars: 3.2'; 'Earth: 9.81'};
            app.Spinner_gravity.Value = 9.81;
            app.AngledegLabel_4 = uilabel(app.SimulationParametersPanel);
            app.AngledegLabel_4.Text = 'Air Density [kg/m³]';
            app.Spinner_air_density = uispinner(app.SimulationParametersPanel);
            app.Spinner_air_density.Limits = [0 10];
            app.Spinner_air_density.ValueChangedFcn = createCallbackFcn(app, @simulationParametersChanged, true);
            app.Spinner_air_density.Tooltip = {'Typical Values:'; 'Earth: 1.24'; 'Moon: 0'; 'Venus: 8.23'; 'Mars: 0.54'};
            app.Spinner_air_density.Value = 1.24;
            app.WindLabel = uilabel(app.SimulationParametersPanel);
            app.WindLabel.Text = 'Wind';
            app.Toggle_wind = uiswitch(app.SimulationParametersPanel, 'toggle');
            app.Toggle_wind.ItemsData = [0 1];
            app.Toggle_wind.ValueChangedFcn = createCallbackFcn(app, @simulationParametersEnable, true);
            app.Toggle_wind.Tooltip = {'Toggle Wind Effects'};
            app.Toggle_wind.Value = 1;
            app.Lamp_Wind = uilamp(app.SimulationParametersPanel);
            app.WindSpeedDownrangeXTab = uitab(app.TabGroup);
            app.WindSpeedDownrangeXTab.Title = 'Wind Speed (Downrange [X])';
            app.GridLayout4 = uigridlayout(app.WindSpeedDownrangeXTab);
            app.WindDiagramPanel = uipanel(app.GridLayout4);
            app.WindDiagramPanel.Title = 'Wind Speed Plot - Positive is Downrange';
            app.GridLayout5 = uigridlayout(app.WindDiagramPanel);
            app.WindPlot = uiaxes(app.GridLayout5);
            title(app.WindPlot, 'Wind Speed over Time')
            xlabel(app.WindPlot, 'Time in [s]')
            ylabel(app.WindPlot, 'Wind Speed in [m/s]')
            zlabel(app.WindPlot, 'Z')
            app.SineFunction01Panel = uipanel(app.GridLayout4);
            app.SineFunction01Panel.Title = 'Sine Function 01';
            app.SwitchSine01 = uiswitch(app.SineFunction01Panel, 'rocker');
            app.SwitchSine01.ItemsData = [0 1];
            app.SwitchSine01.ValueChangedFcn = createCallbackFcn(app, @windPanelSine01, true);
            app.SwitchSine01.Value = 0;
            app.PhaseAngleLabel = uilabel(app.SineFunction01Panel);
            app.PhaseAngleLabel.Text = 'Phase Angle [°]';
            app.Spinner_PhaseAngle01 = uispinner(app.SineFunction01Panel);
            app.Spinner_PhaseAngle01.Limits = [-360 360];
            app.Spinner_PhaseAngle01.ValueChangedFcn = createCallbackFcn(app, @windPanelSine01, true);
            app.AmplitudemsSpinnerLabel = uilabel(app.SineFunction01Panel);
            app.AmplitudemsSpinnerLabel.Text = 'Amplitude [m/s]';
            app.Spinner_Amplitude01 = uispinner(app.SineFunction01Panel);
            app.Spinner_Amplitude01.Limits = [-100 100];
            app.Spinner_Amplitude01.ValueChangedFcn = createCallbackFcn(app, @windPanelSine01, true);
            app.OffsetmsSpinnerLabel = uilabel(app.SineFunction01Panel);
            app.OffsetmsSpinnerLabel.Text = 'Offset [m/s]';
            app.Spinner_Offset01 = uispinner(app.SineFunction01Panel);
            app.Spinner_Offset01.Limits = [-100 100];
            app.Spinner_Offset01.ValueChangedFcn = createCallbackFcn(app, @windPanelSine01, true);
            app.PeriodsSpinnerLabel = uilabel(app.SineFunction01Panel);
            app.PeriodsSpinnerLabel.Text = 'Period [s]';
            app.Spinner_Period01 = uispinner(app.SineFunction01Panel);
            app.Spinner_Period01.Limits = [0.01 3600];
            app.Spinner_Period01.ValueChangedFcn = createCallbackFcn(app, @windPanelSine01, true);
            app.Spinner_Period01.Value = 1;
            app.LampSine01 = uilamp(app.SineFunction01Panel);
            app.SineFunction02Panel = uipanel(app.GridLayout4);
            app.SineFunction02Panel.Title = 'Sine Function 02';
            app.SwitchSine02 = uiswitch(app.SineFunction02Panel, 'rocker');
            app.SwitchSine02.ItemsData = [0 1];
            app.SwitchSine02.ValueChangedFcn = createCallbackFcn(app, @windPanelSine02, true);
            app.SwitchSine02.Value = 0;
            app.PhaseAngleLabel_2 = uilabel(app.SineFunction02Panel);
            app.PhaseAngleLabel_2.Text = 'Phase Angle [°]';
            app.Spinner_PhaseAngle02 = uispinner(app.SineFunction02Panel);
            app.Spinner_PhaseAngle02.Limits = [-360 360];
            app.Spinner_PhaseAngle02.ValueChangedFcn = createCallbackFcn(app, @windPanelSine02, true);
            app.AmplitudemsSpinnerLabel_2 = uilabel(app.SineFunction02Panel);
            app.AmplitudemsSpinnerLabel_2.Text = 'Amplitude [m/s]';
            app.Spinner_Amplitude02 = uispinner(app.SineFunction02Panel);
            app.Spinner_Amplitude02.Limits = [-100 100];
            app.Spinner_Amplitude02.ValueChangedFcn = createCallbackFcn(app, @windPanelSine02, true);
            app.OffsetmsSpinnerLabel_2 = uilabel(app.SineFunction02Panel);
            app.OffsetmsSpinnerLabel_2.Text = 'Offset [m/s]';
            app.Spinner_Offset02 = uispinner(app.SineFunction02Panel);
            app.Spinner_Offset02.Limits = [-100 100];
            app.Spinner_Offset02.ValueChangedFcn = createCallbackFcn(app, @windPanelSine02, true);
            app.PeriodsSpinnerLabel_2 = uilabel(app.SineFunction02Panel);
            app.PeriodsSpinnerLabel_2.Text = 'Period [s]';
            app.Spinner_Period02 = uispinner(app.SineFunction02Panel);
            app.Spinner_Period02.Limits = [0.01 3600];
            app.Spinner_Period02.ValueChangedFcn = createCallbackFcn(app, @windPanelSine02, true);
            app.Spinner_Period02.Value = 1;
            app.LampSine02 = uilamp(app.SineFunction02Panel);
            app.SineFunction03Panel = uipanel(app.GridLayout4);
            app.SineFunction03Panel.Title = 'Sine Function 03';
            app.SwitchSine03 = uiswitch(app.SineFunction03Panel, 'rocker');
            app.SwitchSine03.ItemsData = [0 1];
            app.SwitchSine03.ValueChangedFcn = createCallbackFcn(app, @windPanelSine03, true);
            app.SwitchSine03.Value = 0;
            app.PhaseAngleLabel_3 = uilabel(app.SineFunction03Panel);
            app.PhaseAngleLabel_3.Text = 'Phase Angle [°]';
            app.Spinner_PhaseAngle03 = uispinner(app.SineFunction03Panel);
            app.Spinner_PhaseAngle03.Limits = [-360 360];
            app.Spinner_PhaseAngle03.ValueChangedFcn = createCallbackFcn(app, @windPanelSine03, true);
            app.AmplitudemsSpinnerLabel_3 = uilabel(app.SineFunction03Panel);
            app.AmplitudemsSpinnerLabel_3.Text = 'Amplitude [m/s]';
            app.Spinner_Amplitude03 = uispinner(app.SineFunction03Panel);
            app.Spinner_Amplitude03.Limits = [-100 100];
            app.Spinner_Amplitude03.ValueChangedFcn = createCallbackFcn(app, @windPanelSine03, true);
            app.OffsetmsSpinnerLabel_3 = uilabel(app.SineFunction03Panel);
            app.OffsetmsSpinnerLabel_3.Text = 'Offset [m/s]';
            app.Spinner_Offset03 = uispinner(app.SineFunction03Panel);
            app.Spinner_Offset03.Limits = [-100 100];
            app.Spinner_Offset03.ValueChangedFcn = createCallbackFcn(app, @windPanelSine03, true);
            app.PeriodsSpinnerLabel_3 = uilabel(app.SineFunction03Panel);
            app.PeriodsSpinnerLabel_3.Text = 'Period [s]';
            app.Spinner_Period03 = uispinner(app.SineFunction03Panel);
            app.Spinner_Period03.Limits = [0.01 3600];
            app.Spinner_Period03.ValueChangedFcn = createCallbackFcn(app, @windPanelSine03, true);
            app.Spinner_Period03.Value = 1;
            app.LampSine03 = uilamp(app.SineFunction03Panel);
            app.WindSpeedCrossrangeYTab = uitab(app.TabGroup);
            app.WindSpeedCrossrangeYTab.Title = 'Wind Speed Crossrange [Y]';
            app.GridLayout4_2 = uigridlayout(app.WindSpeedCrossrangeYTab);
            app.WindDiagramPanel_2 = uipanel(app.GridLayout4_2);
            app.WindDiagramPanel_2.Title = 'Wind Speed Plot - Positive is Crossrange';
            app.GridLayout5_2 = uigridlayout(app.WindDiagramPanel_2);
            app.WindPlot_2 = uiaxes(app.GridLayout5_2);
            title(app.WindPlot_2, 'Wind Speed over Time')
            xlabel(app.WindPlot_2, 'Time in [s]')
            ylabel(app.WindPlot_2, 'Wind Speed in [m/s]')
            zlabel(app.WindPlot_2, 'Z')
            app.SineFunction01Panel_2 = uipanel(app.GridLayout4_2);
            app.SineFunction01Panel_2.Title = 'Sine Function 01';
            app.SwitchSine01_2 = uiswitch(app.SineFunction01Panel_2, 'rocker');
            app.SwitchSine01_2.ItemsData = [0 1];
            app.SwitchSine01_2.ValueChangedFcn = createCallbackFcn(app, @Spinner_PhaseAngle01_2ValueChanged, true);
            app.SwitchSine01_2.Value = 0;
            app.PhaseAngleLabel_4 = uilabel(app.SineFunction01Panel_2);
            app.PhaseAngleLabel_4.Text = 'Phase Angle [°]';
            app.Spinner_PhaseAngle01_2 = uispinner(app.SineFunction01Panel_2);
            app.Spinner_PhaseAngle01_2.Limits = [-360 360];
            app.Spinner_PhaseAngle01_2.ValueChangedFcn = createCallbackFcn(app, @Spinner_PhaseAngle01_2ValueChanged, true);
            app.AmplitudemsSpinnerLabel_4 = uilabel(app.SineFunction01Panel_2);
            app.AmplitudemsSpinnerLabel_4.Text = 'Amplitude [m/s]';
            app.Spinner_Amplitude01_2 = uispinner(app.SineFunction01Panel_2);
            app.Spinner_Amplitude01_2.Limits = [-100 100];
            app.Spinner_Amplitude01_2.ValueChangedFcn = createCallbackFcn(app, @Spinner_PhaseAngle01_2ValueChanged, true);
            app.OffsetmsSpinnerLabel_4 = uilabel(app.SineFunction01Panel_2);
            app.OffsetmsSpinnerLabel_4.Text = 'Offset [m/s]';
            app.Spinner_Offset01_2 = uispinner(app.SineFunction01Panel_2);
            app.Spinner_Offset01_2.Limits = [-100 100];
            app.Spinner_Offset01_2.ValueChangedFcn = createCallbackFcn(app, @Spinner_PhaseAngle01_2ValueChanged, true);
            app.PeriodsSpinnerLabel_4 = uilabel(app.SineFunction01Panel_2);
            app.PeriodsSpinnerLabel_4.Text = 'Period [s]';
            app.Spinner_Period01_2 = uispinner(app.SineFunction01Panel_2);
            app.Spinner_Period01_2.Limits = [0.01 3600];
            app.Spinner_Period01_2.ValueChangedFcn = createCallbackFcn(app, @Spinner_PhaseAngle01_2ValueChanged, true);
            app.Spinner_Period01_2.Value = 1;
            app.LampSine01_2 = uilamp(app.SineFunction01Panel_2);
            app.SineFunction02Panel_2 = uipanel(app.GridLayout4_2);
            app.SineFunction02Panel_2.Title = 'Sine Function 02';
            app.SwitchSine02_2 = uiswitch(app.SineFunction02Panel_2, 'rocker');
            app.SwitchSine02_2.ItemsData = [0 1];
            app.SwitchSine02_2.ValueChangedFcn = createCallbackFcn(app, @Spinner_PhaseAngle01_2ValueChanged, true);
            app.SwitchSine02_2.Value = 0;
            app.PhaseAngleLabel_5 = uilabel(app.SineFunction02Panel_2);
            app.PhaseAngleLabel_5.Text = 'Phase Angle [°]';
            app.Spinner_PhaseAngle02_2 = uispinner(app.SineFunction02Panel_2);
            app.Spinner_PhaseAngle02_2.Limits = [-360 360];
            app.Spinner_PhaseAngle02_2.ValueChangedFcn = createCallbackFcn(app, @Spinner_PhaseAngle01_2ValueChanged, true);
            app.AmplitudemsSpinnerLabel_5 = uilabel(app.SineFunction02Panel_2);
            app.AmplitudemsSpinnerLabel_5.Text = 'Amplitude [m/s]';
            app.Spinner_Amplitude02_2 = uispinner(app.SineFunction02Panel_2);
            app.Spinner_Amplitude02_2.Limits = [-100 100];
            app.Spinner_Amplitude02_2.ValueChangedFcn = createCallbackFcn(app, @Spinner_PhaseAngle01_2ValueChanged, true);
            app.OffsetmsSpinnerLabel_5 = uilabel(app.SineFunction02Panel_2);
            app.OffsetmsSpinnerLabel_5.Text = 'Offset [m/s]';
            app.Spinner_Offset02_2 = uispinner(app.SineFunction02Panel_2);
            app.Spinner_Offset02_2.Limits = [-100 100];
            app.Spinner_Offset02_2.ValueChangedFcn = createCallbackFcn(app, @Spinner_PhaseAngle01_2ValueChanged, true);
            app.PeriodsSpinnerLabel_5 = uilabel(app.SineFunction02Panel_2);
            app.PeriodsSpinnerLabel_5.Text = 'Period [s]';
            app.Spinner_Period02_2 = uispinner(app.SineFunction02Panel_2);
            app.Spinner_Period02_2.Limits = [0.01 3600];
            app.Spinner_Period02_2.ValueChangedFcn = createCallbackFcn(app, @Spinner_PhaseAngle01_2ValueChanged, true);
            app.Spinner_Period02_2.Value = 1;
            app.LampSine02_2 = uilamp(app.SineFunction02Panel_2);
            app.SineFunction03Panel_2 = uipanel(app.GridLayout4_2);
            app.SineFunction03Panel_2.Title = 'Sine Function 03';
            app.SwitchSine03_2 = uiswitch(app.SineFunction03Panel_2, 'rocker');
            app.SwitchSine03_2.ItemsData = [0 1];
            app.SwitchSine03_2.ValueChangedFcn = createCallbackFcn(app, @Spinner_PhaseAngle01_2ValueChanged, true);
            app.SwitchSine03_2.Value = 0;
            app.PhaseAngleLabel_6 = uilabel(app.SineFunction03Panel_2);
            app.PhaseAngleLabel_6.Text = 'Phase Angle [°]';
            app.Spinner_PhaseAngle03_2 = uispinner(app.SineFunction03Panel_2);
            app.Spinner_PhaseAngle03_2.Limits = [-360 360];
            app.Spinner_PhaseAngle03_2.ValueChangedFcn = createCallbackFcn(app, @Spinner_PhaseAngle01_2ValueChanged, true);
            app.AmplitudemsSpinnerLabel_6 = uilabel(app.SineFunction03Panel_2);
            app.AmplitudemsSpinnerLabel_6.Text = 'Amplitude [m/s]';
            app.Spinner_Amplitude03_2 = uispinner(app.SineFunction03Panel_2);
            app.Spinner_Amplitude03_2.Limits = [-100 100];
            app.Spinner_Amplitude03_2.ValueChangedFcn = createCallbackFcn(app, @Spinner_PhaseAngle01_2ValueChanged, true);
            app.OffsetmsSpinnerLabel_6 = uilabel(app.SineFunction03Panel_2);
            app.OffsetmsSpinnerLabel_6.Text = 'Offset [m/s]';
            app.Spinner_Offset03_2 = uispinner(app.SineFunction03Panel_2);
            app.Spinner_Offset03_2.Limits = [-100 100];
            app.Spinner_Offset03_2.ValueChangedFcn = createCallbackFcn(app, @Spinner_PhaseAngle01_2ValueChanged, true);
            app.PeriodsSpinnerLabel_6 = uilabel(app.SineFunction03Panel_2);
            app.PeriodsSpinnerLabel_6.Text = 'Period [s]';
            app.Spinner_Period03_2 = uispinner(app.SineFunction03Panel_2);
            app.Spinner_Period03_2.Limits = [0.01 3600];
            app.Spinner_Period03_2.ValueChangedFcn = createCallbackFcn(app, @Spinner_PhaseAngle01_2ValueChanged, true);
            app.Spinner_Period03_2.Value = 1;
            app.LampSine03_2 = uilamp(app.SineFunction03Panel_2);
            app.WindSpeedCrossrangeZTab = uitab(app.TabGroup);
            app.WindSpeedCrossrangeZTab.Title = 'Wind Speed Crossrange [Z]';
            app.GridLayout4_3 = uigridlayout(app.WindSpeedCrossrangeZTab);
            app.WindDiagramPanel_3 = uipanel(app.GridLayout4_3);
            app.WindDiagramPanel_3.Title = 'Wind Speed Plot - Positive is Up';
            app.GridLayout5_3 = uigridlayout(app.WindDiagramPanel_3);
            app.WindPlot_3 = uiaxes(app.GridLayout5_3);
            title(app.WindPlot_3, 'Wind Speed over Time')
            xlabel(app.WindPlot_3, 'Time in [s]')
            ylabel(app.WindPlot_3, 'Wind Speed in [m/s]')
            zlabel(app.WindPlot_3, 'Z')
            app.SineFunction01Panel_3 = uipanel(app.GridLayout4_3);
            app.SineFunction01Panel_3.Title = 'Sine Function 01';
            app.SwitchSine01_3 = uiswitch(app.SineFunction01Panel_3, 'rocker');
            app.SwitchSine01_3.ItemsData = [0 1];
            app.SwitchSine01_3.ValueChangedFcn = createCallbackFcn(app, @Spinner_PhaseAngle01_3ValueChanged, true);
            app.SwitchSine01_3.Value = 0;
            app.PhaseAngleLabel_7 = uilabel(app.SineFunction01Panel_3);
            app.PhaseAngleLabel_7.Text = 'Phase Angle [°]';
            app.Spinner_PhaseAngle01_3 = uispinner(app.SineFunction01Panel_3);
            app.Spinner_PhaseAngle01_3.Limits = [-360 360];
            app.Spinner_PhaseAngle01_3.ValueChangedFcn = createCallbackFcn(app, @Spinner_PhaseAngle01_3ValueChanged, true);
            app.AmplitudemsSpinnerLabel_7 = uilabel(app.SineFunction01Panel_3);
            app.AmplitudemsSpinnerLabel_7.Text = 'Amplitude [m/s]';
            app.Spinner_Amplitude01_3 = uispinner(app.SineFunction01Panel_3);
            app.Spinner_Amplitude01_3.Limits = [-100 100];
            app.Spinner_Amplitude01_3.ValueChangedFcn = createCallbackFcn(app, @Spinner_PhaseAngle01_3ValueChanged, true);
            app.OffsetmsSpinnerLabel_7 = uilabel(app.SineFunction01Panel_3);
            app.OffsetmsSpinnerLabel_7.Text = 'Offset [m/s]';
            app.Spinner_Offset01_3 = uispinner(app.SineFunction01Panel_3);
            app.Spinner_Offset01_3.Limits = [-100 100];
            app.Spinner_Offset01_3.ValueChangedFcn = createCallbackFcn(app, @Spinner_PhaseAngle01_3ValueChanged, true);
            app.PeriodsSpinnerLabel_7 = uilabel(app.SineFunction01Panel_3);
            app.PeriodsSpinnerLabel_7.Text = 'Period [s]';
            app.Spinner_Period01_3 = uispinner(app.SineFunction01Panel_3);
            app.Spinner_Period01_3.Limits = [0.01 3600];
            app.Spinner_Period01_3.ValueChangedFcn = createCallbackFcn(app, @Spinner_PhaseAngle01_3ValueChanged, true);
            app.Spinner_Period01_3.Value = 1;
            app.LampSine01_3 = uilamp(app.SineFunction01Panel_3);
            app.SineFunction02Panel_3 = uipanel(app.GridLayout4_3);
            app.SineFunction02Panel_3.Title = 'Sine Function 02';
            app.SwitchSine02_3 = uiswitch(app.SineFunction02Panel_3, 'rocker');
            app.SwitchSine02_3.ItemsData = [0 1];
            app.SwitchSine02_3.ValueChangedFcn = createCallbackFcn(app, @Spinner_PhaseAngle01_3ValueChanged, true);
            app.SwitchSine02_3.Value = 0;
            app.PhaseAngleLabel_8 = uilabel(app.SineFunction02Panel_3);
            app.PhaseAngleLabel_8.Text = 'Phase Angle [°]';
            app.Spinner_PhaseAngle02_3 = uispinner(app.SineFunction02Panel_3);
            app.Spinner_PhaseAngle02_3.Limits = [-360 360];
            app.Spinner_PhaseAngle02_3.ValueChangedFcn = createCallbackFcn(app, @Spinner_PhaseAngle01_3ValueChanged, true);
            app.AmplitudemsSpinnerLabel_8 = uilabel(app.SineFunction02Panel_3);
            app.AmplitudemsSpinnerLabel_8.Text = 'Amplitude [m/s]';
            app.Spinner_Amplitude02_3 = uispinner(app.SineFunction02Panel_3);
            app.Spinner_Amplitude02_3.Limits = [-100 100];
            app.Spinner_Amplitude02_3.ValueChangedFcn = createCallbackFcn(app, @Spinner_PhaseAngle01_3ValueChanged, true);
            app.OffsetmsSpinnerLabel_8 = uilabel(app.SineFunction02Panel_3);
            app.OffsetmsSpinnerLabel_8.Text = 'Offset [m/s]';
            app.Spinner_Offset02_3 = uispinner(app.SineFunction02Panel_3);
            app.Spinner_Offset02_3.Limits = [-100 100];
            app.Spinner_Offset02_3.ValueChangedFcn = createCallbackFcn(app, @Spinner_PhaseAngle01_3ValueChanged, true);
            app.PeriodsSpinnerLabel_8 = uilabel(app.SineFunction02Panel_3);
            app.PeriodsSpinnerLabel_8.Text = 'Period [s]';
            app.Spinner_Period02_3 = uispinner(app.SineFunction02Panel_3);
            app.Spinner_Period02_3.Limits = [0.01 3600];
            app.Spinner_Period02_3.ValueChangedFcn = createCallbackFcn(app, @Spinner_PhaseAngle01_3ValueChanged, true);
            app.Spinner_Period02_3.Value = 1;
            app.LampSine02_3 = uilamp(app.SineFunction02Panel_3);
            app.SineFunction03Panel_3 = uipanel(app.GridLayout4_3);
            app.SineFunction03Panel_3.Title = 'Sine Function 03';
            app.SwitchSine03_3 = uiswitch(app.SineFunction03Panel_3, 'rocker');
            app.SwitchSine03_3.ItemsData = [0 1];
            app.SwitchSine03_3.ValueChangedFcn = createCallbackFcn(app, @Spinner_PhaseAngle01_3ValueChanged, true);
            app.SwitchSine03_3.Value = 0;
            app.PhaseAngleLabel_9 = uilabel(app.SineFunction03Panel_3);
            app.PhaseAngleLabel_9.Text = 'Phase Angle [°]';
            app.Spinner_PhaseAngle03_3 = uispinner(app.SineFunction03Panel_3);
            app.Spinner_PhaseAngle03_3.Limits = [-360 360];
            app.Spinner_PhaseAngle03_3.ValueChangedFcn = createCallbackFcn(app, @Spinner_PhaseAngle01_3ValueChanged, true);
            app.AmplitudemsSpinnerLabel_9 = uilabel(app.SineFunction03Panel_3);
            app.AmplitudemsSpinnerLabel_9.Text = 'Amplitude [m/s]';
            app.Spinner_Amplitude03_3 = uispinner(app.SineFunction03Panel_3);
            app.Spinner_Amplitude03_3.Limits = [-100 100];
            app.Spinner_Amplitude03_3.ValueChangedFcn = createCallbackFcn(app, @Spinner_PhaseAngle01_3ValueChanged, true);
            app.OffsetmsSpinnerLabel_9 = uilabel(app.SineFunction03Panel_3);
            app.OffsetmsSpinnerLabel_9.Text = 'Offset [m/s]';
            app.Spinner_Offset03_3 = uispinner(app.SineFunction03Panel_3);
            app.Spinner_Offset03_3.Limits = [-100 100];
            app.Spinner_Offset03_3.ValueChangedFcn = createCallbackFcn(app, @Spinner_PhaseAngle01_3ValueChanged, true);
            app.PeriodsSpinnerLabel_9 = uilabel(app.SineFunction03Panel_3);
            app.PeriodsSpinnerLabel_9.Text = 'Period [s]';
            app.Spinner_Period03_3 = uispinner(app.SineFunction03Panel_3);
            app.Spinner_Period03_3.Limits = [0.01 3600];
            app.Spinner_Period03_3.ValueChangedFcn = createCallbackFcn(app, @Spinner_PhaseAngle01_3ValueChanged, true);
            app.Spinner_Period03_3.Value = 1;
            app.LampSine03_3 = uilamp(app.SineFunction03Panel_3);
            app.Tab_SimPlots = uitab(app.TabGroup);
            app.Tab_SimPlots.Title = 'Simulation Plot Overview';
            app.GridLayout6 = uigridlayout(app.Tab_SimPlots);
            app.Plot2D_xz = uiaxes(app.GridLayout6);
            title(app.Plot2D_xz, 'x-z Plot')
            xlabel(app.Plot2D_xz, 'Distance from Origin [m]')
            ylabel(app.Plot2D_xz, 'Height [m]')
            zlabel(app.Plot2D_xz, 'Z')
            app.Plot2D_xz.XLim = [0 Inf];
            app.Plot2D_xz.YLim = [0 Inf];
            app.Plot2D_xz.ZLim = [0 Inf];
            app.Plot2D_tE1 = uiaxes(app.GridLayout6);
            title(app.Plot2D_tE1, 't-E Plot')
            xlabel(app.Plot2D_tE1, 'Time [s]')
            ylabel(app.Plot2D_tE1, 'Energy [J]')
            zlabel(app.Plot2D_tE1, 'Z')
            app.Plot2D_tv = uiaxes(app.GridLayout6);
            title(app.Plot2D_tv, 't-v Plot')
            xlabel(app.Plot2D_tv, 'Time [s]')
            ylabel(app.Plot2D_tv, 'Total Velocity [m/s]')
            zlabel(app.Plot2D_tv, 'Z')
            app.Plot2D_tz = uiaxes(app.GridLayout6);
            title(app.Plot2D_tz, 't-z Plot')
            xlabel(app.Plot2D_tz, 'Time [s]')
            ylabel(app.Plot2D_tz, 'Height [m]')
            zlabel(app.Plot2D_tz, 'Z')
            app.DTrajectoryTab = uitab(app.TabGroup);
            app.DTrajectoryTab.Title = '3D-Trajectory';
            app.GridLayout7 = uigridlayout(app.DTrajectoryTab);
            app.Plot3D_Trajectory = uiaxes(app.GridLayout7);
            title(app.Plot3D_Trajectory, '3D Trajectory Plot')
            xlabel(app.Plot3D_Trajectory, 'X')
            ylabel(app.Plot3D_Trajectory, 'Y')
            zlabel(app.Plot3D_Trajectory, 'Z')
            app.EnergyPlotsTab = uitab(app.TabGroup);
            app.EnergyPlotsTab.Title = 'Energy Plots';
            app.GridLayout8 = uigridlayout(app.EnergyPlotsTab);
            app.Plot2D_tE2 = uiaxes(app.GridLayout8);
            title(app.Plot2D_tE2, 'E-t Plot')
            xlabel(app.Plot2D_tE2, 'Time [s]')
            ylabel(app.Plot2D_tE2, 'Energy [J]')
            zlabel(app.Plot2D_tE2, 'Z')
            app.Plot2D_tEkin = uiaxes(app.GridLayout8);
            title(app.Plot2D_tEkin, 'Ekin-t Plot')
            xlabel(app.Plot2D_tEkin, 'Time [s]')
            ylabel(app.Plot2D_tEkin, 'Kinetic Energy [J]')
            zlabel(app.Plot2D_tEkin, 'Z')
            app.Plot2D_tErot = uiaxes(app.GridLayout8);
            title(app.Plot2D_tErot, 'Erot-t Plot')
            xlabel(app.Plot2D_tErot, 'Time [s]')
            ylabel(app.Plot2D_tErot, 'Rotational Energy [J]')
            zlabel(app.Plot2D_tErot, 'Z')
            app.Plot2D_tEpot = uiaxes(app.GridLayout8);
            title(app.Plot2D_tEpot, 'Epot-t Plot')
            xlabel(app.Plot2D_tEpot, 'Time [s]')
            ylabel(app.Plot2D_tEpot, 'Potential Energy [J]')
            zlabel(app.Plot2D_tEpot, 'Z')
            app.SpeedPlotsTab = uitab(app.TabGroup);
            app.SpeedPlotsTab.Title = 'Speed Plots';
            app.GridLayout10 = uigridlayout(app.SpeedPlotsTab);
            app.Plot2D_tvx = uiaxes(app.GridLayout10);
            title(app.Plot2D_tvx, 't-vx Plot')
            xlabel(app.Plot2D_tvx, 'Time [s]')
            ylabel(app.Plot2D_tvx, 'Velocity [m/s]')
            zlabel(app.Plot2D_tvx, 'Z')
            app.Plot2D_tvy = uiaxes(app.GridLayout10);
            title(app.Plot2D_tvy, 't-vy Plot')
            xlabel(app.Plot2D_tvy, 'Time [s]')
            ylabel(app.Plot2D_tvy, 'Velocity [m/s]')
            zlabel(app.Plot2D_tvy, 'Z')
            app.Plot2D_tvz = uiaxes(app.GridLayout10);
            title(app.Plot2D_tvz, 't-vz Plot')
            xlabel(app.Plot2D_tvz, 'Time [s]')
            ylabel(app.Plot2D_tvz, 'Velocity [m/s]')
            zlabel(app.Plot2D_tvz, 'Z')
            app.Plot2D_tv2 = uiaxes(app.GridLayout10);
            title(app.Plot2D_tv2, 't-v Plot')
            xlabel(app.Plot2D_tv2, 'Time [s]')
            ylabel(app.Plot2D_tv2, 'Velocity [m/s]')
            zlabel(app.Plot2D_tv2, 'Z')
            app.PositionPlotsTab = uitab(app.TabGroup);
            app.PositionPlotsTab.Title = 'Position Plots';
            app.GridLayout9 = uigridlayout(app.PositionPlotsTab);
            app.Plot2D_tz2 = uiaxes(app.GridLayout9);
            title(app.Plot2D_tz2, 't-z Plot')
            xlabel(app.Plot2D_tz2, 'Time [s]')
            ylabel(app.Plot2D_tz2, 'Altitude [m]')
            zlabel(app.Plot2D_tz2, 'Z')
            app.Plot2D_ty = uiaxes(app.GridLayout9);
            title(app.Plot2D_ty, 't-y Plot')
            xlabel(app.Plot2D_ty, 'Time [s]')
            ylabel(app.Plot2D_ty, 'Position Crossrange [m]')
            zlabel(app.Plot2D_ty, 'Z')
            app.Plot2D_tx = uiaxes(app.GridLayout9);
            title(app.Plot2D_tx, 't-x Plot')
            xlabel(app.Plot2D_tx, 'Time [s]')
            ylabel(app.Plot2D_tx, 'Position Downrange [m]')
            zlabel(app.Plot2D_tx, 'Z')
            % Show the figure after all components are created
            app.ARTEMISUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AirsoftCalculationTool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ARTEMISUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ARTEMISUIFigure)
        end
    end
end